classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BuildMapImage        matlab.ui.control.Image
        BuildMapBackground   matlab.ui.container.Panel
        MapBuildingLamp      matlab.ui.control.Lamp
        MapbuildingLabel     matlab.ui.control.Label
        Panel_2              matlab.ui.container.Panel
        SpeedKnob            matlab.ui.control.Knob
        SpeedLabel           matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        AutoexploreLabel     matlab.ui.control.Label
        AutoexploreSwitch    matlab.ui.control.Switch
        SimulationLamp       matlab.ui.control.Lamp
        SimulationLampLabel  matlab.ui.control.Label
        ConnectionLamp       matlab.ui.control.Lamp
        ConnectionLampLabel  matlab.ui.control.Label
        Panel                matlab.ui.container.Panel
        BtnFullStop          matlab.ui.control.StateButton
        BtnBwdLeft           matlab.ui.control.StateButton
        BtnFwdLeft           matlab.ui.control.StateButton
        BtnBwdRight          matlab.ui.control.StateButton
        BtnFwdRight          matlab.ui.control.StateButton
        BtnLeft              matlab.ui.control.StateButton
        BtnBackward          matlab.ui.control.StateButton
        BtnRight             matlab.ui.control.StateButton
        BtnForward           matlab.ui.control.StateButton
        BtnCW                matlab.ui.control.StateButton
        BtnCCW               matlab.ui.control.StateButton
        BtnSimulate          matlab.ui.control.Button
        BtnConnect           matlab.ui.control.Button
        mapOccupancyAxes     matlab.ui.control.UIAxes
        mapTrajectoryAxes    matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties(Constant)

    end
    properties (Access = private)
        statusOffColor = [0.5, 0.5, 0.5];
        statusOnColor = 'green';
        wheelsSpeed = 10.0; 
        baseControl % Base control object
        connection  % Instance of RobotConnection
        laserScanner % Instance of LaserScanner class
        robotPose % Instance of RobotPoseEstimation class 
        slamHandler % Instance of the lidarSlam class
        explorer %instance of the explorer class
        autoExploreSpeed = 4;
    end
    
    properties (Access = public)
       
    end
    
    methods (Access = private)

        function toggleConnectButton(app)
            if app.connection.clientID == -1
                app.BtnConnect.Text = 'Connect';
                app.BtnConnect.Icon = 'icons/Connect.png';
                app.ConnectionLamp.Color = app.statusOffColor;
            else
                app.BtnConnect.Text = 'Disconnect';
                app.BtnConnect.Icon = 'icons/Disconnect.png';
                app.ConnectionLamp.Color = app.statusOnColor;
            end
        end

        function toggleSimulationButton(app,state)
            if app.connection.clientID > -1
                app.BtnSimulate.Enable = "on";
            else 
                app.BtnSimulate.Enable = state;
            end

            if app.connection.isSimulationRunning
                app.SimulationLamp.Color = app.statusOnColor;
                app.BtnSimulate.Icon = 'icons/Stop.png';
            else
                app.SimulationLamp.Color = app.statusOffColor;
                app.BtnSimulate.Icon = 'icons/Start.png';
            end
        end

        function toggleBuildMapButtons(app)
            if isempty(app.slamHandler) || isempty(app.explorer)
                app.MapBuildingLamp.Color = app.statusOffColor;
                app.BuildMapImage.BackgroundColor = "#F0F0F0";
            elseif app.slamHandler.isUpdating || app.explorer.isExploring
                app.MapBuildingLamp.Color = app.statusOnColor;
                app.BuildMapImage.BackgroundColor = "white";
            else
                app.MapBuildingLamp.Color = app.statusOffColor;
                app.BuildMapImage.BackgroundColor = "#F0F0F0";
            end
        end
 
        function toggleMovementButtons(app,state)
            
            app.SpeedKnob.Enable = state;
            app.SpeedLabel.Enable = state;
            
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, ...
                               app.BtnCW, app.BtnCCW, app.BtnFwdLeft, app.BtnFwdRight, ...
                               app.BtnBwdLeft, app.BtnBwdRight, app.BtnFullStop};

            % Loop through all movement buttons and set their status
            for i = 1:length(movementButtons)
                movementButtons{i}.Enable = state;
            end
        end

        function toggleAutoExploreButton(app,state)
            app.AutoexploreSwitch.Enable = state;
            app.AutoexploreLabel.Enable = state;
        end

        function toggleControls(app,state)
            app.toggleSimulationButton(state);
            app.toggleBuildMapButtons();
            app.toggleMovementButtons(state);
        end

        function buttonsInitialSetup(app)
        
            app.toggleConnectButton();
            app.toggleSimulationButton('off');
            app.toggleBuildMapButtons();
            app.toggleMovementButtons('off');
            app.toggleAutoExploreButton('off');

            %assign unique identifiers to the movement buttons so we can
            %have a single generic method to move the robot

            app.BtnForward.Tag = 'FORWARD';
            app.BtnBackward.Tag = 'BACKWARD';
            app.BtnLeft.Tag = 'LEFT';
            app.BtnRight.Tag = 'RIGHT';
            app.BtnCW.Tag = 'CLOCKWISE';
            app.BtnCCW.Tag = 'COUNTER_CLOCKWISE';
            app.BtnFwdLeft.Tag = 'DIAG_FL';
            app.BtnFwdRight.Tag = 'DIAG_FR';
            app.BtnBwdLeft.Tag = 'DIAG_BL';
            app.BtnBwdRight.Tag = 'DIAG_BR';
            app.BtnFullStop.Tag = 'FULL_STOP';

            app.SpeedKnob.Value = app.wheelsSpeed;
        end

        function componentsInitialSetup(app)
                        
            % Retrieve wheel handles from CoppeliaSim (use correct object names)
            [handleWheelFR, ~] = app.connection.getHandle('rollingJoint_fr');
            [handleWheelFL, ~] = app.connection.getHandle('rollingJoint_fl');
            [handleWheelRR, ~] = app.connection.getHandle('rollingJoint_rr');
            [handleWheelRL, ~] = app.connection.getHandle('rollingJoint_rl');
            
            % Store the handles in an array to pass to BaseControl
            wheelHandles = [handleWheelFR, handleWheelFL, handleWheelRR, handleWheelRL];
            
            % Initialize the BaseControl object
            app.baseControl = BaseControl(wheelHandles);  

            % Initialize SLAM algorithm with new parameters
            maxRange = 10; % Slightly below the max Lidar range
            mapResolution = 20; % 20 cells per meter, 5cm precision
            loopClosureThreshold = 210;
            loopClosureSearchRadius = 8;
    
            % Initialize robotPose class
            app.robotPose = RobotPose();

            % Initialize LaserScanner and set the intial robot position
            app.laserScanner = LaserScanner(app.connection, app.robotPose);

            % Initialize the SLAM handler object
            app.slamHandler = SLAMHandler(app.laserScanner,app.robotPose,mapResolution, maxRange, loopClosureThreshold, loopClosureSearchRadius, app.mapTrajectoryAxes, app.mapOccupancyAxes);
            

            % Initialize Explorer object with references to the necessary components
            % safeDistance = 0.4;  % Safe distance threshold for obstacle avoidance
            % gapThreshold = 1;
            % app.explorer = Explorer(app.slamHandler, app.baseControl, safeDistance, app.autoExploreSpeed, app.connection, app.AutoexploreSwitch,gapThreshold,app.laserScanner);
            app.explorer = Explorer(app.connection,app.baseControl,app.slamHandler, app.laserScanner ,app.robotPose,app.mapOccupancyAxes);

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize RobotConnection
            app.connection = RobotConnection();
            app.buttonsInitialSetup();
        end

        % Button pushed function: BtnConnect
        function BtnConnectButtonPushed(app, event)
            % Check the current state of the connection
            if app.connection.clientID == -1
                success = app.connection.connect();
                if success
                    disp('Connected to CoppeliaSim.');
                    app.componentsInitialSetup();
                    
                    % Change the connect button to disconnect
                    app.toggleConnectButton();

                    % Enable Simulation controls
                    app.toggleSimulationButton('on');
                else
                    %failed to connect to Coppelia
                     uialert(app.UIFigure, 'Connection failed.Something went wrong, please check the logs', 'Status');
                end
            else
                %check if it's running and stop the simulation 
                if app.connection.isSimulationRunning
                    app.connection.stopSimulation();
                    app.toggleAutoExploreButton('off');
                end
                app.slamHandler.stopUpdating();
                app.explorer.stopExploration();
                app.connection.disconnect();

                % Change back the connect button to connect
                app.toggleConnectButton();

                % Disable UI controls 
                app.toggleControls("off");
            end
        end

        % Button pushed function: BtnSimulate
        function BtnSimulateButtonPushed(app, event)
            % Check if the client is connected
            if app.connection.clientID > -1

                 if ~app.connection.isSimulationRunning
                    app.connection.startSimulation();
                 
                    if app.connection.isSimulationRunning
                         % Enable UI controls
                        app.toggleControls('on');
                        app.toggleAutoExploreButton('on');

                    end
                 else
                    app.slamHandler.stopUpdating();
                    app.explorer.stopExploration();

                     % Stop the simulation
                    app.connection.stopSimulation();
                    
                    % Check if the simulation stopped successfully
                    if ~app.connection.isSimulationRunning
                        % Disable UI controls
                        app.toggleControls('off');
                        app.toggleAutoExploreButton('off');
                        disp('Simulation stopped.');
                    end
                end
            else
                disp('Please connect to CoppeliaSim before starting the simulation.');
            end
        end

        % Value changed function: SpeedKnob
        function KnobSpeedValueChanged(app, event)
            app.wheelsSpeed  = app.SpeedKnob.Value;
        end

        % Value changed function: BtnBackward, BtnBwdLeft, BtnBwdRight, 
        % ...and 8 other components
        function BtnMovementValueChanged(app, event)
            value = event.Source.Value;
            movementType = event.Source.Tag;  % Get the movement type from the button's tag
            
            % Create a list of all movement buttons
            movementButtons = {app.BtnForward, app.BtnBackward, app.BtnLeft, app.BtnRight, ...
                               app.BtnFwdLeft, app.BtnFwdRight, app.BtnBwdLeft, app.BtnBwdRight, ...
                               app.BtnCW, app.BtnCCW};

            % Take out from the list the pressed button
            pressedButton = event.Source;
            remainingButtons = movementButtons(~cellfun(@(btn) isequal(btn, pressedButton), movementButtons));
            
            % Loop through all the other movement buttons and set their value to 0
                for i = 1:length(remainingButtons)
                    remainingButtons{i}.Value = 0;
                end

            % if the Full stop button is clicked
            if strcmp(movementType, 'FULL_STOP')
                % Stop the robot
                app.baseControl.stop(app.connection.sim, app.connection.clientID);
                app.BtnFullStop.Value = 0;
                app.explorer.stopExploration();
                app.slamHandler.stopUpdating();
            else
                if ~app.slamHandler.isUpdating
                    app.slamHandler.startUpdating();
                end
                % For other movement buttons, move the robot if pressed
                if value == 1  % Button is pressed
                    app.baseControl.move(app.connection.sim, app.connection.clientID,app.baseControl.WheelMovements.(movementType), app.wheelsSpeed);
                else
                    app.baseControl.stop(app.connection.sim, app.connection.clientID);
                end
            end
            app.toggleBuildMapButtons();
        end

        % Value changed function: AutoexploreSwitch
        function AutoexploreSwitchValueChanged(app, event)
            if ~app.explorer.isExploring
                % Start exploration
                app.SpeedKnob.Value = app.autoExploreSpeed;
                if ~app.slamHandler.isUpdating
                    app.slamHandler.startUpdating();
                end    
                app.explorer.startExploration();
                disp('Exploration started.');
            else
                % Stop exploration
                app.SpeedKnob.Value = app.wheelsSpeed;
                app.explorer.stopExploration();
                disp('Exploration stopped.');
            end
            app.toggleBuildMapButtons();
        end

        % Close request function: UIFigure
        function AppCloseRequested(app, event)
               %check if it's running and stop the simulation 
                if app.connection.isSimulationRunning
                    app.connection.stopSimulation();
                end
                if app.slamHandler.isUpdating
                    app.slamHandler.stopUpdating();
                end
                if app.explorer.isExploring
                    app.explorer.stopExploration();
                end
                app.connection.disconnect();
                delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @AppCloseRequested, true);
            app.mapTrajectoryAxes = uiaxes(app.UIFigure);
            title(app.mapTrajectoryAxes, 'Trajectory map')
            xlabel(app.mapTrajectoryAxes, 'X')
            ylabel(app.mapTrajectoryAxes, 'Y')
            zlabel(app.mapTrajectoryAxes, 'Z')
            app.mapOccupancyAxes = uiaxes(app.UIFigure);
            title(app.mapOccupancyAxes, 'Occupancy map')
            xlabel(app.mapOccupancyAxes, 'X')
            ylabel(app.mapOccupancyAxes, 'Y')
            zlabel(app.mapOccupancyAxes, 'Z')
            app.BtnConnect = uibutton(app.UIFigure, 'push');
            app.BtnConnect.ButtonPushedFcn = createCallbackFcn(app, @BtnConnectButtonPushed, true);
            app.BtnConnect.Icon = fullfile(pathToMLAPP, 'icons', 'Connect.png');
            app.BtnConnect.Text = '';
            app.BtnSimulate = uibutton(app.UIFigure, 'push');
            app.BtnSimulate.ButtonPushedFcn = createCallbackFcn(app, @BtnSimulateButtonPushed, true);
            app.BtnSimulate.Icon = fullfile(pathToMLAPP, 'icons', 'Start.png');
            app.BtnSimulate.Enable = 'off';
            app.BtnSimulate.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.BtnCCW = uibutton(app.Panel, 'state');
            app.BtnCCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCCW.Enable = 'off';
            app.BtnCCW.Icon = fullfile(pathToMLAPP, 'icons', 'CCW.png');
            app.BtnCCW.Text = '';
            app.BtnCW = uibutton(app.Panel, 'state');
            app.BtnCW.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnCW.Enable = 'off';
            app.BtnCW.Icon = fullfile(pathToMLAPP, 'icons', 'CW.png');
            app.BtnCW.Text = '';
            app.BtnForward = uibutton(app.Panel, 'state');
            app.BtnForward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnForward.Enable = 'off';
            app.BtnForward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowUp.png');
            app.BtnForward.Text = '';
            app.BtnRight = uibutton(app.Panel, 'state');
            app.BtnRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnRight.Enable = 'off';
            app.BtnRight.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowRight.png');
            app.BtnRight.Text = '';
            app.BtnBackward = uibutton(app.Panel, 'state');
            app.BtnBackward.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBackward.Enable = 'off';
            app.BtnBackward.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowDown.png');
            app.BtnBackward.Text = '';
            app.BtnLeft = uibutton(app.Panel, 'state');
            app.BtnLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnLeft.Enable = 'off';
            app.BtnLeft.Icon = fullfile(pathToMLAPP, 'icons', 'ArrowLeft.png');
            app.BtnLeft.Text = '';
            app.BtnFwdRight = uibutton(app.Panel, 'state');
            app.BtnFwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFwdRight.Enable = 'off';
            app.BtnFwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'FwdRight.png');
            app.BtnFwdRight.Text = '';
            app.BtnBwdRight = uibutton(app.Panel, 'state');
            app.BtnBwdRight.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBwdRight.Enable = 'off';
            app.BtnBwdRight.Icon = fullfile(pathToMLAPP, 'icons', 'BwdRight.png');
            app.BtnBwdRight.Text = '';
            app.BtnFwdLeft = uibutton(app.Panel, 'state');
            app.BtnFwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFwdLeft.Enable = 'off';
            app.BtnFwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'FwdLeft.png');
            app.BtnFwdLeft.Text = '';
            app.BtnBwdLeft = uibutton(app.Panel, 'state');
            app.BtnBwdLeft.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnBwdLeft.Enable = 'off';
            app.BtnBwdLeft.Icon = fullfile(pathToMLAPP, 'icons', 'BwdLeft.png');
            app.BtnBwdLeft.Text = '';
            app.BtnFullStop = uibutton(app.Panel, 'state');
            app.BtnFullStop.ValueChangedFcn = createCallbackFcn(app, @BtnMovementValueChanged, true);
            app.BtnFullStop.Enable = 'off';
            app.BtnFullStop.Icon = fullfile(pathToMLAPP, 'icons', 'Stop.png');
            app.BtnFullStop.Text = '';
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.SimulationLampLabel = uilabel(app.UIFigure);
            app.SimulationLampLabel.Text = 'Simulation';
            app.SimulationLamp = uilamp(app.UIFigure);
            app.Panel_3 = uipanel(app.UIFigure);
            app.AutoexploreSwitch = uiswitch(app.Panel_3, 'slider');
            app.AutoexploreSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoexploreSwitchValueChanged, true);
            app.AutoexploreSwitch.Enable = 'off';
            app.AutoexploreLabel = uilabel(app.Panel_3);
            app.AutoexploreLabel.Enable = 'off';
            app.AutoexploreLabel.Text = 'Auto explore';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SpeedLabel = uilabel(app.Panel_2);
            app.SpeedLabel.Enable = 'off';
            app.SpeedLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.Panel_2, 'continuous');
            app.SpeedKnob.Limits = [0 50];
            app.SpeedKnob.ValueChangedFcn = createCallbackFcn(app, @KnobSpeedValueChanged, true);
            app.SpeedKnob.Enable = 'off';
            app.MapbuildingLabel = uilabel(app.UIFigure);
            app.MapbuildingLabel.Text = 'Map building';
            app.MapBuildingLamp = uilamp(app.UIFigure);
            app.BuildMapBackground = uipanel(app.UIFigure);
            app.BuildMapImage = uiimage(app.UIFigure);
            app.BuildMapImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'map.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end